classdef Spectral_Clustering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MulticlassSpectralClusteringLabel  matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        RunButton              matlab.ui.control.Button
        GaussiankernelparameterEditField  matlab.ui.control.NumericEditField
        GaussiankernelparameterEditFieldLabel  matlab.ui.control.Label
        DataListBox            matlab.ui.control.ListBox
        DataListBoxLabel       matlab.ui.control.Label
        AlgorithmListBox       matlab.ui.control.ListBox
        AlgorithmListBoxLabel  matlab.ui.control.Label
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
            
            Z=matfile("app.mat").Z; 
            X=matfile("app.mat").X;
            A=matfile("app.mat").A;
            K=matfile("app.mat").K;
    end
        methods (Access = private)

        
        function display_data_2(app,dat)

            switch dat
                case '1D'
            cla(app.UIAxes_2,'reset')
            hold (app.UIAxes_2,"on")
            histogram(app.UIAxes_2,app.Z(1:20),'FaceColor','#0072BD');
            histogram(app.UIAxes_2,app.Z(21:40),'FaceColor','#0072BD');
            histogram(app.UIAxes_2,app.Z(41:60),'FaceColor','#0072BD');
            title(app.UIAxes_2,'1D data',FontSize=16,FontWeight='bold');
            ylim(app.UIAxes_2,'padded');
            hold (app.UIAxes_2,"off");

                case '2D'
            cla(app.UIAxes_2,'reset')
            gscatter(app.UIAxes_2,app.X(:,1),app.X(:,2),'','','')
            legend(app.UIAxes_2,'off');
            xlabel(app.UIAxes_2,'')
            ylabel(app.UIAxes_2,'')
            ylim(app.UIAxes_2,'padded');
            title(app.UIAxes_2,'2D data',FontSize=16,FontWeight='bold');

                case 'Spirals'
            cla(app.UIAxes_2,'reset')
            gscatter(app.UIAxes_2,app.A(:,1),app.A(:,2),'','','');
            xlabel(app.UIAxes_2,'')
            ylabel(app.UIAxes_2,'')
            ylim(app.UIAxes_2,'padded');
            title(app.UIAxes_2,'Spirals',FontSize=16,FontWeight='bold');


                case 'Rings'
            cla(app.UIAxes_2,'reset')
            gscatter(app.UIAxes_2,app.K(:,1),app.K(:,2),'','','')
            title(app.UIAxes_2,'Rings',FontSize=16,FontWeight='bold');
            xlabel(app.UIAxes_2,'')
            ylim(app.UIAxes_2,'padded');
            ylabel(app.UIAxes_2,'')

            end
        end
        
        function  display_data_1(app,data,P)
            switch data
                case '1D'
            cla(app.UIAxes,'reset')
            hold (app.UIAxes,"on")
            histogram(app.UIAxes,app.Z(P==1));
            histogram(app.UIAxes,app.Z(P==2));
            histogram(app.UIAxes,app.Z(P==3));
            title(app.UIAxes,'1D data',FontSize=16,FontWeight='bold');
            ylim(app.UIAxes,'padded');
            hold (app.UIAxes,"off");

                case '2D'
            cla(app.UIAxes,'reset')
            gscatter(app.UIAxes,app.X(:,1),app.X(:,2),P,'','','')
            legend(app.UIAxes,'off');
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            ylim(app.UIAxes,'padded');
            legend(app.UIAxes,'off');
            title(app.UIAxes,'2D data',FontSize=16,FontWeight='bold');

                case 'Spirals'
            cla(app.UIAxes,'reset')
            gscatter(app.UIAxes,app.A(:,1),app.A(:,2),P,'','','');
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            ylim(app.UIAxes,'padded');
            legend(app.UIAxes,'off');
            title(app.UIAxes,'Spirals',FontSize=16,FontWeight='bold');


                case 'Rings'
            cla(app.UIAxes,'reset')
            gscatter(app.UIAxes,app.K(:,1),app.K(:,2),P,'','','')
            title(app.UIAxes,'Rings',FontSize=16,FontWeight='bold');
            xlabel(app.UIAxes,'')
            ylim(app.UIAxes,'padded');
            legend(app.UIAxes,'off');
            ylabel(app.UIAxes,'')
            end


        end
        

        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            P=laplacian(gaussian_kernel(app.Z,2),3);
            display_data_2(app,'1D');
            display_data_1(app,'1D',P);


        end

        % Value changed function: DataListBox
        function DataListBoxValueChanged(app, event)

            value = app.DataListBox.Value;            

            switch value
                case '1D'
            display_data_2(app,value)

                case '2D'
            display_data_2(app,value)

                case 'Spirals'
            display_data_2(app,value)


                case 'Rings'
            display_data_2(app,value)

            end


        end

        % Clicked callback: DataListBox
        function DataListBoxClicked(app, event)
            
        end

        % Callback function: GaussiankernelparameterEditField, RunButton
        function RunButtonPushed(app, event)
            app.Lamp.Color=[0.85,0.33,0.10];
            pause(0.2);
            s=app.GaussiankernelparameterEditField.Value;
            alg=app.AlgorithmListBox.Value;
            data=app.DataListBox.Value;
            
            switch alg
                case 'Laplacian'

            switch data
                case '1D'
                    P=laplacian(gaussian_kernel(app.Z,s),3);
                    display_data_1(app,data,P)
                case '2D'
                    P=laplacian(gaussian_kernel(app.X,s),3);
                    display_data_1(app,data,P)
                case 'Spirals'
                    P=laplacian(gaussian_kernel(app.A,s),3);
                    display_data_1(app,data,P)
                case 'Rings'
                    P=laplacian(gaussian_kernel(app.K,s),3);
                    display_data_1(app,data,P)
            end


                case 'Symetric Laplacian'
            switch data
                case '1D'
                    P=laplacian_sym(gaussian_kernel(app.Z,s),3);
                    display_data_1(app,data,P)
                case '2D'
                    P=laplacian_sym(gaussian_kernel(app.X,s),3);
                    display_data_1(app,data,P)
                case 'Spirals'
                    P=laplacian_sym(gaussian_kernel(app.A,s),3);
                    display_data_1(app,data,P)
                case 'Rings'
                    P=laplacian_sym(gaussian_kernel(app.K,s),3);
                    display_data_1(app,data,P)
            end

                case 'Normalized Laplacian'

            switch data
                case '1D'
                    P=laplacian_rw(gaussian_kernel(app.Z,s),3);
                    display_data_1(app,data,P)
                case '2D'
                    P=laplacian_rw(gaussian_kernel(app.X,s),3);
                    display_data_1(app,data,P)
                case 'Spirals'
                    P=laplacian_rw(gaussian_kernel(app.A,s),3);
                    display_data_1(app,data,P)
                case 'Rings'
                    P=laplacian_rw(gaussian_kernel(app.K,s),3);
                    display_data_1(app,data,P)
            end

                case 'Normalized cuts'

            switch data
                case '1D'
                    P=normalized_cuts(gaussian_kernel(app.Z,s),3);
                    display_data_1(app,data,P)
                case '2D'
                    P=normalized_cuts(gaussian_kernel(app.X,s),3);
                    display_data_1(app,data,P)
                case 'Spirals'
                    P=normalized_cuts(gaussian_kernel(app.A,s),3);
                    display_data_1(app,data,P)
                case 'Rings'
                    P=normalized_cuts(gaussian_kernel(app.K,s),3);
                    display_data_1(app,data,P)
            end
            end
            app.Lamp.Color=[0.39,0.83,0.07];
            pause(0.2);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.AlgorithmListBoxLabel = uilabel(app.UIFigure);
            app.AlgorithmListBoxLabel.Text = 'Algorithm';
            app.AlgorithmListBox = uilistbox(app.UIFigure);
            app.AlgorithmListBox.Items = {'Laplacian', 'Symetric Laplacian', 'Normalized Laplacian', 'Normalized cuts'};
            app.AlgorithmListBox.Value = 'Laplacian';
            app.DataListBoxLabel = uilabel(app.UIFigure);
            app.DataListBoxLabel.Text = 'Data';
            app.DataListBox = uilistbox(app.UIFigure);
            app.DataListBox.Items = {'1D', '2D', 'Spirals', 'Rings'};
            app.DataListBox.ValueChangedFcn = createCallbackFcn(app, @DataListBoxValueChanged, true);
            app.DataListBox.ClickedFcn = createCallbackFcn(app, @DataListBoxClicked, true);
            app.DataListBox.Value = '1D';
            app.GaussiankernelparameterEditFieldLabel = uilabel(app.UIFigure);
            app.GaussiankernelparameterEditFieldLabel.Text = 'Gaussian kernel parameter';
            app.GaussiankernelparameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaussiankernelparameterEditField.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.GaussiankernelparameterEditField.Value = 2;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Tag = 'L';
            app.MulticlassSpectralClusteringLabel = uilabel(app.UIFigure);
            app.MulticlassSpectralClusteringLabel.Text = 'Multiclass Spectral Clustering';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spectral_Clustering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end